classdef Latent_Visualisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TRANSFORMEDDATAZLabel       matlab.ui.control.Label
        ORIGINALDATAXLabel          matlab.ui.control.Label
        ANGLEOFVIEWPOINTLabel       matlab.ui.control.Label
        QLabel_2                    matlab.ui.control.Label
        LOADINGMATRIXOUTCOMELabel   matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        LTextArea                   matlab.ui.control.TextArea
        QLabel                      matlab.ui.control.Label
        omega_ySlider               matlab.ui.control.Slider
        omega_ySliderLabel          matlab.ui.control.Label
        omega_xSlider               matlab.ui.control.Slider
        omega_xSliderLabel          matlab.ui.control.Label
        UseEigenvectorsPCACheckBox  matlab.ui.control.CheckBox
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Z %= load("PCA_Dataset.mat","Z") % Dataset that is preloaded
        UseEigen % Boolean indicating whether to use Eigenvalues in PCA computation or not
        Theta = struct() % Structure containing the specific theta values for the sliders
        % For plotting first figure
        x_plane 
        y_plane
        z_plane
        X_projected
        % For plotting second figure
        t
        t_axes
        Q
        QQ
    end
    
    methods (Access = private)
        
        function PrePlotAndProject(app,X,UseEigenVectors,theta)
            % Calculating Q
            if UseEigenVectors == true  % Calculate the first two eigenvectors
                [app.Q,~] = eigs(X' * X, 2);
            else                % Create a rotatation matrix
                app.Q = [1 0 0; 0 cos(theta.x) -sin(theta.x); 0 sin(theta.x) cos(theta.x)]*[cos(theta.y) 0; 0 1; -sin(theta.y) 0];
            end
            
            app.LTextArea.Value{1} = num2str(app.Q(1,:),1);
            app.LTextArea.Value{2} = num2str(app.Q(2,:),1);
            app.LTextArea.Value{3} = num2str(app.Q(3,:),1);

            app.QQ = app.Q'*app.Q;
            app.TextArea.Value{1} = num2str(app.QQ(1,:),1);
            app.TextArea.Value{2} = num2str(app.QQ(2,:),1);
            % Calculation of plot arrays
            xG = meshgrid([-20 20]);
            xV = reshape(xG, 4, []);
            yV = reshape(xG', 4, []);
            zV = xV+yV;
            X_out = [xV yV zV]*app.Q*app.Q';
            app.x_plane = reshape(X_out(:,1), 2, []);
            app.y_plane = reshape(X_out(:,2), 2, []);
            app.z_plane = reshape(X_out(:,3), 2, []);
            app.t = X*app.Q;
            app.X_projected = app.t*app.Q';
            orig_axes = 3*[1 0 0; 0 0 0; 0 1 0; 0 0 0; 0 0 -1];
            app.t_axes = orig_axes*app.Q;
            
            % Now for the plotting part
            s = surf(app.UIAxes,app.x_plane,app.y_plane,app.z_plane);
            s.FaceColor = 'k'; s.FaceAlpha = 0.2; s.LineStyle = 'none';
            hold(app.UIAxes,"on")
            plot3(app.UIAxes,[X(:,1) app.X_projected(:,1)].', [X(:,2) app.X_projected(:,2)].',[X(:,3) app.X_projected(:,3)].',"LineStyle",'-',"Color",	'#0072BD',"Marker",".")
            plot3(app.UIAxes,app.X_projected(:,1), app.X_projected(:,2), app.X_projected(:,3),"Marker",".",'MarkerSize',8,"Color",'#D95319',"LineStyle","none")
            plot3(app.UIAxes,4*[-1 1 0 0 0 0 0 0], 4*[0 0 0 -1 1 0 0 0], 4*[0 0 0 0 0 0 -1 1],'k');  % Axes
            axis(app.UIAxes,"square",[-4 4 -4 4 -4 4])
            hold(app.UIAxes,"off")

            plot(app.UIAxes2,app.t(:,1),app.t(:,2),"Marker",".","Color",'#D95319','LineStyle',"none")
            hold(app.UIAxes2,"on") 
            plot(app.UIAxes2,app.t_axes(:,1),app.t_axes(:,2),'k','MarkerSize',8);
            axis(app.UIAxes2,"square",[-4 4 -4 4 -4 4])
            hold(app.UIAxes2,"off") 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.Z = load('PCA_Dataset.mat',"Z")
            load("PCA_Dataset.mat")
            app.Z = evalin('base','Z');
            app.UseEigen = false;
            app.Theta.x = 0*pi/180;
            app.Theta.y = 0*pi/180;
            PrePlotAndProject(app,app.Z,app.UseEigen,app.Theta)
            %app.UIFigure.WindowState = 'maximized';
        end

        % Value changed function: UseEigenvectorsPCACheckBox
        function UseEigenvectorsPCACheckBoxValueChanged(app, event)
            value = app.UseEigenvectorsPCACheckBox.Value;
            app.UseEigen = value;
            PrePlotAndProject(app,app.Z,app.UseEigen,app.Theta)
        end

        % Value changing function: omega_ySlider
        function omega_ySliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta.y = changingValue;
            PrePlotAndProject(app,app.Z,app.UseEigen,app.Theta)
        end

        % Value changed function: omega_ySlider
        function omega_ySliderValueChanged(app, event)
            value = app.omega_ySlider.Value;
            app.Theta.y = value;
            PrePlotAndProject(app,app.Z,app.UseEigen,app.Theta)
        end

        % Value changing function: omega_xSlider
        function omega_xSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta.x = changingValue;
            PrePlotAndProject(app,app.Z,app.UseEigen,app.Theta)
        end

        % Value changed function: omega_xSlider
        function omega_xSliderValueChanged(app, event)
            value = app.omega_xSlider.Value;
            app.Theta.x = value;
            PrePlotAndProject(app,app.Z,app.UseEigen,app.Theta)
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function EditFieldValueChanging(app, event)
            %
        end

        % Value changing function: LTextArea
        function LTextAreaValueChanging(app, event)
            %changingValue = event.Value;
            %event.Value = string(app.Q)
        end

        % Value changed function: LTextArea
        function LTextAreaValueChanged(app, event)
            %app.LTextAreaValueChanged.Value = string(app.Q)
            %value = app.LTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X_1')
            ylabel(app.UIAxes, 'X_2')
            zlabel(app.UIAxes, 'X_3')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'z_1')
            ylabel(app.UIAxes2, 'z_2')
            zlabel(app.UIAxes2, 'Z')
            app.UseEigenvectorsPCACheckBox = uicheckbox(app.UIFigure);
            app.UseEigenvectorsPCACheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEigenvectorsPCACheckBoxValueChanged, true);
            app.UseEigenvectorsPCACheckBox.Text = '      Use Eigenvectors (PCA)';
            app.omega_xSliderLabel = uilabel(app.UIFigure);
            app.omega_xSliderLabel.Interpreter = 'latex';
            app.omega_xSliderLabel.Text = '$\omega_{x}$';
            app.omega_xSlider = uislider(app.UIFigure);
            app.omega_xSlider.Limits = [-180 180];
            app.omega_xSlider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.omega_xSlider.MajorTickLabels = {'-180', '-120', '-60', '0', '60', '120', '180'};
            app.omega_xSlider.ValueChangedFcn = createCallbackFcn(app, @omega_xSliderValueChanged, true);
            app.omega_xSlider.ValueChangingFcn = createCallbackFcn(app, @omega_xSliderValueChanging, true);
            app.omega_ySliderLabel = uilabel(app.UIFigure);
            app.omega_ySliderLabel.Interpreter = 'latex';
            app.omega_ySliderLabel.Text = '$\omega_{y}$';
            app.omega_ySlider = uislider(app.UIFigure);
            app.omega_ySlider.Limits = [-180 180];
            app.omega_ySlider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.omega_ySlider.MajorTickLabels = {'-180', '-120', '-60', '0', '60', '120', '180'};
            app.omega_ySlider.ValueChangedFcn = createCallbackFcn(app, @omega_ySliderValueChanged, true);
            app.omega_ySlider.ValueChangingFcn = createCallbackFcn(app, @omega_ySliderValueChanging, true);
            app.QLabel = uilabel(app.UIFigure);
            app.QLabel.Text = 'L =';
            app.LTextArea = uitextarea(app.UIFigure);
            app.LTextArea.ValueChangedFcn = createCallbackFcn(app, @LTextAreaValueChanged, true);
            app.LTextArea.ValueChangingFcn = createCallbackFcn(app, @LTextAreaValueChanging, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.LOADINGMATRIXOUTCOMELabel = uilabel(app.UIFigure);
            app.LOADINGMATRIXOUTCOMELabel.Text = 'LOADING MATRIX OUTCOME';
            app.QLabel_2 = uilabel(app.UIFigure);
            app.QLabel_2.Text = 'L''L = ';
            app.ANGLEOFVIEWPOINTLabel = uilabel(app.UIFigure);
            app.ANGLEOFVIEWPOINTLabel.Text = 'ANGLE OF VIEWPOINT';
            app.ORIGINALDATAXLabel = uilabel(app.UIFigure);
            app.ORIGINALDATAXLabel.Text = 'ORIGINAL DATA X';
            app.TRANSFORMEDDATAZLabel = uilabel(app.UIFigure);
            app.TRANSFORMEDDATAZLabel.Text = 'TRANSFORMED DATA Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Latent_Visualisation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end